classdef appUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        LampLabel       matlab.ui.control.Label
        Lamp            matlab.ui.control.Lamp
        DownloadButton  matlab.ui.control.Button
        RunButton       matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        AddImageButton  matlab.ui.control.Button
        RefreshButton   matlab.ui.control.Button
        Tree            matlab.ui.container.Tree
    end

    
    properties (Access = private)
        % Store user data in the app
        Data
        % Columns used in the Data table
        % Any changes to the cols needs to be reflected in AddImageButtonPushed AND startupFcn
        Headers = {'zoom','time','timeHrs','timeDays', 'filePath', 'fileName', 'outPath'};
        
        % Store config data in the app
        Config 
    end
    
    methods (Access = public)
        
        
        function refreshTree(app,toSel)

            % Whenever something changes, make sure the download button is disabled
            app.DownloadButton.Enable = 'off';
            
            % If they didn't specify an index
            if(nargin < 2)
                % Which node is currently selected?
                selectedNode = app.Tree.SelectedNodes;
                
                try
                    % Store the index of that node
                    prevIndex = selectedNode.NodeData;
                catch % If there's an error, nothing was selected
                    prevIndex = -1; % Store -1 instead
                end
            else
            % Otherwise, use the value provided
                prevIndex = toSel;
            end


            % Empty the tree first
            all = app.Tree.Children;
            all.delete;

            % Get the Dataset Name
            parentName = char(app.Config.('general').('SetName'));
            % Create the parent node
            parent = uitreenode(app.Tree, 'Text', parentName, 'NodeData',0);

            % Create child nodes from input table
            for i = 1:size(app.Data,1) % For each row in the table
                title = getImgTitle(app,i);                

                if(i==prevIndex) % If it was previously selected
                    prevNode = uitreenode(parent,'Text',title,'NodeData',i); % Add it to the Tree and store it for later
                else % Otherwise
                    uitreenode(parent,'Text',title,'NodeData',i); % Just add it to the Tree
                end
                
            end

            % Expand the tree, for visual purposes
            expand(app.Tree);

            % If there was a node previously selected
            if (prevIndex > 0)
                app.Tree.SelectedNodes = prevNode; % Reselect it now
            else % Otherwise, return to the parent node
                app.Tree.SelectedNodes = parent;
            
            end
            
        end


        % Function called to get the current value from a row in the Config table
        function thisSetting = getSetting(app,type,option)
            % Return the Config value
            thisSetting = app.Config.(type).(option);
            
        end

        % Function called to change a value in the Config table
        function changeSetting(app,type,option,value)
            % Select the chosen option and add the new values to the table
            app.Config.(type).(option) = value;
            
        end

        % Function called to change the Dataset Name
        function changeDatasetName(app,newName)
            % Calls the normal function
            changeSetting(app,'general','SetName',newName)
            % But refreshes the tree, too
            refreshTree(app);
        end        

        % Function called to get one image row from the Data table
        function thisImage = getImage(app,imgIndex)
            % Return the row for that index
            thisImage = app.Data(imgIndex,:);
        end
        
        % Function called to change one cell on a particular row in the Data table
        function changeImage(app,imgIndex,option,value)
            % Select the chosen image by Index
            thisImage = app.Data(imgIndex,:);
            % Change that option to the new value
            thisImage.(option) = value;
            % Save the updated row into the table
            app.Data(imgIndex,:) = thisImage;

            % Refresh the tree
            %refreshTree(app);

        end
        
        function moveImgUp(app, imgIndex)
            % Swap a row with the row above it
            
            % First, save the row above
            above = imgIndex-1;
            rowAbove = app.Data(above,:);

            % Then replace the row above
            app.Data(above,:) = app.Data(imgIndex,:);

            % Finally, put the other row back
            app.Data(imgIndex,:) = rowAbove;
            
            % Refresh the tree, and make sure the new index is selected
            refreshTree(app,above);

            % Also reload the panel to update
            TreeSelectionChanged(app);
        end
        
        function moveImgDown(app, imgIndex)
            % Swap a row with the row below it
            
            % First, save the row below
            below = imgIndex+1;
            rowBelow = app.Data(below,:);

            % Then replace the row below
            app.Data(below,:) = app.Data(imgIndex,:);

            % Finally, put the other row back
            app.Data(imgIndex,:) = rowBelow;
            
            % Refresh the tree, and make sure the new index is selected
            refreshTree(app,below);

            % Also reload the panel to update
            TreeSelectionChanged(app);
            
        end
        
        function title = getImgTitle(app,imgIndex)
            % Get the row from the table
            thisNode = app.Data(imgIndex,:);

            % Determine the node's name
            title = "s" + imgIndex + " " + thisNode{1,'zoom'} + "X ";
            
            % Add the correct time
            if(thisNode{1,'time'} == "Hours")
                title = title + thisNode{1,'timeHrs'} + "h";
            elseif(thisNode{1,'time'} == "Days")
                title = title + thisNode{1,'timeDays'} + "d";
            end
        end
    end
    
    methods (Access = private)
        
        function runImg(app)
            % Find how many images we've got
            tblSz = size(app.Data);
            tblRows = tblSz(1);

            % For each row in the table
            for i = 1:tblRows
                % Get the row of details for the image
                imgRow = getImage(app,i);
                % Kick off the algorithm
                % And record the location of the output files
                output = coldrive(app.Config,imgRow,i);

                % Save the output path back into the table
                changeImage(app, i, 'outPath', output);
            end
        end
        
        function download(app)
            % Create a central folder to use, based on the 1st image's output path
            outDir = fullfile(getImage(app,1).outPath,"output");
            % If its already there, delete it so we can start fresh
            if isfolder(outDir)
                try
                    rmdir(outDir);
                end
            end
            % Create the central output folder
            mkdir(outDir);
            
            % Go through each of the images and bring their output to the same folder
            tblSz = size(app.Data);
            tblRows = tblSz(1);
            for i = 1:tblRows
                % Get all files in the output directory for this image
                outputFiles = fullfile(getImage(app,i).outPath,'*');
                % Move the files to our central output folder
                movefile(outputFiles,outDir);
            end

            % Create a name for the download
            name = strcat(getSetting(app,'general','SetName'),'-download.zip');

            % Promt the user to save
            [name,path] = uiputfile("*.zip", "Save AS", name);
            
            % Check the path is valid
            % In case they closed the window
            if (path ~= 0)

                % Set the full output
                outFile = fullfile(path,name);
    
                % Compress the output dir into a single zip
                zip(outFile,outDir);

            end
            
            % Re-enable the warnings
            warning('on','MATLAB:MKDIR:DirectoryExists')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Project Settings defaults and save to Config
            app.Config = readstruct('default.xml');
           
            % Create blank table for user data
            table = cell2table(cell(0,7),'VariableNames',app.Headers);

            % Save Data table in the app
            app.Data = table;

            % Refresh the tree
            refreshTree(app);
            
            % Load Project Settings into the panel by default
            projSettings(app,app.Panel);


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            % Which node is selected?
            selectedNode = app.Tree.SelectedNodes;
            
            try
                % Try and get the index from that node
                currentIndex = selectedNode.NodeData;

            catch % If there's an error, nothing is selected
                return; % Stop there
            end

             % Clear the panel first
            panelFamily = app.Panel.Children;
            panelFamily.delete;

            % If it's the parent, index is 0
            if (currentIndex == 0)
                % Display the Project Settings in the panel
                projSettings(app,app.Panel);

            %Otherwise, it must be a child
            else 
                % Display the correct Image Settings in the panel
                imgSettings(app,app.Panel,currentIndex);
            end
 
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshTree(app);

        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)

            % Create a new row
            newRow = {1,"Hours",0,0,"","",""};

            % Add it to the end of the Data table
            app.Data = [app.Data; newRow];

            % Refresh the tree
            refreshTree(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Update the Lamp
            app.Lamp.Color = [0.93,0.69,0.13];
            app.LampLabel.Text = 'Running';
            % And disable the inputs
            app.Tree.Enable = 'off';
            app.RefreshButton.Enable = 'off';
            app.AddImageButton.Enable = 'off';
            % And clear the panel
            panelFamily = app.Panel.Children;
            panelFamily.delete;

            % Wait for the ui to update
            waitfor(app.LampLabel,'Text','Running');
            
            % Run the calculations
            runImg(app);
            
            % Finally, enable the Download button
            app.DownloadButton.Enable = 'on';
            % And update the Lamp again
            app.Lamp.Color = [0,1,0];
            app.LampLabel.Text = 'Done';

            % Wait for the ui to update
            waitfor(app.LampLabel,'Text','Done');
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            % Update the Lamp
            app.Lamp.Color = [0.93,0.69,0.13];
            app.LampLabel.Text = 'Downloading';
            % Wait for the ui to update
            waitfor(app.LampLabel,'Text','Downloading');
            
            % Run the download
            download(app);

            % Re-enable the inputs
            app.Tree.Enable = 'on';
            app.RefreshButton.Enable = 'on';
            app.AddImageButton.Enable = 'on';
            % And update the Lamp again
            app.Lamp.Color = [0,1,0];
            app.LampLabel.Text = 'Done';

            % Wait for the ui to update
            waitfor(app.LampLabel,'Text','Done');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.AddImageButton = uibutton(app.UIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add Image';
            app.Panel = uipanel(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.Lamp = uilamp(app.UIFigure);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Idle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end