classdef tammicol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusLabel                   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        InvertcolourCheckBox          matlab.ui.control.CheckBox
        BinaryCSV                     matlab.ui.control.CheckBox
        ImageProcessingOptionsLabel   matlab.ui.control.Label
        ExportOptionsLabel            matlab.ui.control.Label
        ImageFormatLabel              matlab.ui.control.Label
        SaveComparison                matlab.ui.control.CheckBox
        SaveBinary                    matlab.ui.control.CheckBox
        SaveCSV                       matlab.ui.control.CheckBox
        OutputFormat                  matlab.ui.control.DropDown
        OutputbinaryformatLabel       matlab.ui.control.Label
        InputFormat                   matlab.ui.control.DropDown
        InputimageformatLabel         matlab.ui.control.Label
        ManualCheck                   matlab.ui.control.CheckBox
        BorderMethod                  matlab.ui.control.DropDown
        BordermethodLabel             matlab.ui.control.Label
        Method                        matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        AdvancedSettingsTab           matlab.ui.container.Tab
        PairMetric                    matlab.ui.control.CheckBox
        AngularMetric                 matlab.ui.control.CheckBox
        RadialMetric                  matlab.ui.control.CheckBox
        Wavenumbers                   matlab.ui.control.CheckBox
        RadiusMethodProportion        matlab.ui.control.Slider
        RadiusmethodproportionSliderLabel  matlab.ui.control.Label
        BinaryMATFile                 matlab.ui.control.CheckBox
        Fourier                       matlab.ui.control.CheckBox
        AdditionalExportOptionsLabel  matlab.ui.control.Label
        StatisticsSettingsLabel       matlab.ui.control.Label
        SaveMAT                       matlab.ui.control.CheckBox
        SaveError                     matlab.ui.control.CheckBox
        SaveIntensity                 matlab.ui.control.CheckBox
        RadiusMethod                  matlab.ui.control.DropDown
        RadiusmethodLabel             matlab.ui.control.Label
        PairSamples                   matlab.ui.control.NumericEditField
        PairsamplesLabel              matlab.ui.control.Label
        MaxCells                      matlab.ui.control.NumericEditField
        MaximumpairpixelsLabel        matlab.ui.control.Label
        PairBins                      matlab.ui.control.NumericEditField
        PairbinsLabel                 matlab.ui.control.Label
        AngularBins                   matlab.ui.control.NumericEditField
        AngularbinsLabel              matlab.ui.control.Label
        RadialBins                    matlab.ui.control.NumericEditField
        RadialbinsLabel               matlab.ui.control.Label
        AnalyseAnnulus                matlab.ui.control.CheckBox
        CentreData                    matlab.ui.control.CheckBox
        OutputDirectory               matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        ShowProgress                  matlab.ui.control.CheckBox
        Statistics                    matlab.ui.control.Button
        ImageProcess                  matlab.ui.control.Button
        DirectoryLabel                matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ImagesAndStatistics           matlab.ui.control.Button
        SelectDirectory               matlab.ui.control.Button
    end

properties (Access = private)
    
    % Image analysis settings
    Settings = struct('Method','Connected',...
        'RemoveBorders','None',...
        'LevelMethod',3,...
        'ManualCheck',false,...
        'BorderMethod','Reduce',...
        'InvertColour',false,...
        'ChiMax',1 ...
        );
    
    % Statistics settings
    Options = struct('nBins',2*[100;100;100],...
        'MaxCells',1e3,...
        'PairSamples',1e3,...
        'RadiusProportion',0,...
        'RadiusMethod','CSR',...
        'CentreData',true,...
        'Annulus',true ...
        );
    
    % File Settings
    Controls = struct('ComputeImage',false',...
        'ComputeStatistics',false,...
        'SaveStatsCSV',true,...
        'SaveFourier',false,...
        'SaveWave',false,...
        'SaveFR',false,...
        'SaveFA',false,...
        'SaveFP',false,...
        'SaveStatsMAT',false,...
        'SaveBinaryMAT',false,...
        'SaveBinaryCSV',false',...
        'SaveBinary',true,...
        'SaveIntensity',false,...
        'SaveError',false,...
        'SaveComparison',false,...
        'ImageExtensionIn','tif',...
        'ImageExtensionOut','tif',...
        'PathIn','',...
        'filename','',...
        'File','',...
        'PathOut','',...
        'ImageSeparator',' ',...
        'ImageSuffix','binary',...
        'DirectorySeparator',' ',...
        'DirectorySuffix','Binary',...
        'ShowProgress',false...
        );
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDirectory
        function SelectDirectoryButton(app, event)
            [app.Controls.File, app.Controls.PathIn] = uigetfile({'*.tif*'}, 'File Selector', 'Multiselect', 'on');
            warning(app.Controls.PathIn)
            warning(app.Controls.File)
            app.DirectoryLabel.Text = app.Controls.PathIn;
        end

        % Button pushed function: ImagesAndStatistics
        function ImagesAndStatisticsButton(app, event)
            app.Lamp.Color = [0.93,0.69,0.13];
            app.StatusLabel.Text = 'Working';
            app.Controls.ComputeImage = true;
            app.Controls.ComputeStatistics = true;
            coldrive(app.Settings,app.Controls,app.Options);
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Done';
        end

        % Callback function
        function RadialBinsField(app, event)
            app.Options.nBins(1) = app.RadialBins.Value;
        end

        % Value changed function: AngularBins
        function AngularBinsField(app, event)
            app.Options.nBins(2) = app.AngularBins.Value;  
        end

        % Value changed function: PairBins
        function PairBinsField(app, event)
            app.Options.nBins(3) = app.PairBins.Value;
        end

        % Value changed function: OutputFormat
        function OutputFormatList(app, event)
            app.Controls.ImageExtensionOut = app.OutputFormat.Value;
        end

        % Value changed function: SaveCSV
        function SaveCSVToggle(app, event)
            app.Controls.SaveCSV = app.SaveCSV.Value;
        end

        % Value changed function: SaveBinary
        function SaveBinaryToggle(app, event)
            app.Controls.SaveBinary = app.SaveBinary.Value;
        end

        % Value changed function: SaveIntensity
        function SaveIntensityToggle(app, event)
            app.Controls.SaveIntensity = app.SaveIntensity.Value;
        end

        % Value changed function: SaveComparison
        function SaveComparisonToggle(app, event)
            app.Controls.SaveComparison = app.SaveComparison.Value;
        end

        % Value changed function: InputFormat
        function InputFormatList(app, event)
            app.Controls.ImageExtensionIn = app.InputFormat.Value;
        end

        % Value changed function: SaveError
        function SaveErrorToggle(app, event)
            app.Controls.SaveError = app.SaveError.Value;
        end

        % Button pushed function: Statistics
        function StatisticsButton(app, event)
            app.Lamp.Color = [0.93,0.69,0.13];
            app.StatusLabel.Text = 'Working';
            app.Controls.ComputeImage = false;
            app.Controls.ComputeStatistics = true;
            coldrive(app.Settings,app.Controls,app.Options);
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Done';
        end

        % Button pushed function: ImageProcess
        function ImageProcessButton(app, event)
            app.Lamp.Color = [0.93,0.69,0.13];
            app.StatusLabel.Text = 'Working';
            app.Controls.ComputeImage = true;
            app.Controls.ComputeStatistics = false;
            coldrive(app.Settings,app.Controls,app.Options);
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Done';
        end

        % Value changed function: SaveMAT
        function SaveMATToggle(app, event)
            app.Controls.SaveMAT = app.SaveMAT.Value;
        end

        % Value changed function: ShowProgress
        function ShowProgressToggle(app, event)
            app.Controls.ShowProgress = app.ShowProgress.Value;
        end

        % Value changed function: MaxCells
        function MaxCellsField(app, event)
            app.Options.MaxCells = app.MaxCells.Value;
        end

        % Value changed function: PairSamples
        function PairSamplesToggle(app, event)
            app.Options.PairSamples = app.PairSamples.Value;
        end

        % Value changed function: ManualCheck
        function ManualCheckToggle(app, event)
            app.Settings.ManualCheck = app.ManualCheck.Value;
        end

        % Value changed function: Method
        function MethodDrop(app, event)
            app.Settings.Method = app.Method.Value;
        end

        % Button pushed function: OutputDirectory
        function OutputDirectoryButton(app, event)
            filename = (app.Controls.PathIn + "Temp Binary\Sample\temp");
            newFile = sprintf('%s binary.tif', filename);
            im = imread(newFile);
            [newFile,path] = uiputfile(newFile);
            resultFilePath = fullfile(path, newFile);
            imwrite(im,resultFilePath);
        end

        % Value changed function: Fourier
        function FourierToggle(app, event)
            app.Controls.SaveFourier = app.Fourier.Value;
        end

        % Value changed function: BinaryCSV
        function BinaryCSVToggle(app, event)
            app.Controls.SaveBinaryCSV = app.BinaryCSV.Value;
        end

        % Value changed function: BinaryMATFile
        function BinaryMATToggle(app, event)
            app.Controls.SaveBinaryMAT = app.BinaryMATFile.Value;
        end

        % Value changed function: InvertcolourCheckBox
        function InvertColourToggle(app, event)
            app.Settings.InvertColour = app.InvertcolourCheckBox.Value;
        end

        % Value changed function: RadiusMethodProportion
        function RadiusProportionSlider(app, event)
            app.Options.RadiusProportion = app.RadiusMethodProportion.Value;
        end

        % Value changed function: Wavenumbers
        function WavenumberToggle(app, event)
            app.Controls.SaveWave = app.Wavenumbers.Value;
        end

        % Value changed function: RadialMetric
        function RadialMetricToggle(app, event)
            app.Controls.SaveFR = app.RadialMetric.Value;
        end

        % Value changed function: AngularMetric
        function AngularMetricToggle(app, event)
            app.Controls.SaveFA = app.AngularMetric.Value;
        end

        % Value changed function: PairMetric
        function PairMetricToggle(app, event)
            app.Controls.SaveFP = app.PairMetric.Value;            
        end

        % Value changed function: BorderMethod
        function BorderMethodDrop(app, event)
            app.Settings.BorderMethod = app.BorderMethod.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectDirectory = uibutton(app.UIFigure, 'push');
            app.SelectDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButton, true);
            app.SelectDirectory.Text = 'Input directory';
            app.ImagesAndStatistics = uibutton(app.UIFigure, 'push');
            app.ImagesAndStatistics.ButtonPushedFcn = createCallbackFcn(app, @ImagesAndStatisticsButton, true);
            app.ImagesAndStatistics.Text = 'Images and statistics';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'TAMMiCol';
            app.DirectoryLabel = uilabel(app.UIFigure);
            app.DirectoryLabel.Text = '...';
            app.ImageProcess = uibutton(app.UIFigure, 'push');
            app.ImageProcess.ButtonPushedFcn = createCallbackFcn(app, @ImageProcessButton, true);
            app.ImageProcess.Text = 'Image process';
            app.Statistics = uibutton(app.UIFigure, 'push');
            app.Statistics.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButton, true);
            app.Statistics.Text = 'Statistics';
            app.ShowProgress = uicheckbox(app.UIFigure);
            app.ShowProgress.ValueChangedFcn = createCallbackFcn(app, @ShowProgressToggle, true);
            app.ShowProgress.Text = 'Show progress bar';
            app.Lamp = uilamp(app.UIFigure);
            app.OutputDirectory = uibutton(app.UIFigure, 'push');
            app.OutputDirectory.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryButton, true);
            app.OutputDirectory.Text = 'Download';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.MethodDropDownLabel = uilabel(app.GeneralTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.Method = uidropdown(app.GeneralTab);
            app.Method.Items = {'Connected', 'Unconnected'};
            app.Method.ValueChangedFcn = createCallbackFcn(app, @MethodDrop, true);
            app.Method.Value = 'Connected';
            app.BordermethodLabel = uilabel(app.GeneralTab);
            app.BordermethodLabel.Text = 'Border method';
            app.BorderMethod = uidropdown(app.GeneralTab);
            app.BorderMethod.Items = {'Reduce', 'Remove'};
            app.BorderMethod.ValueChangedFcn = createCallbackFcn(app, @BorderMethodDrop, true);
            app.BorderMethod.Value = 'Reduce';
            app.ManualCheck = uicheckbox(app.GeneralTab);
            app.ManualCheck.ValueChangedFcn = createCallbackFcn(app, @ManualCheckToggle, true);
            app.ManualCheck.Text = 'Manual check';
            app.InputimageformatLabel = uilabel(app.GeneralTab);
            app.InputimageformatLabel.Text = 'Input image format';
            app.InputFormat = uidropdown(app.GeneralTab);
            app.InputFormat.Items = {'tif', 'bmp', 'png', 'jpg'};
            app.InputFormat.ValueChangedFcn = createCallbackFcn(app, @InputFormatList, true);
            app.InputFormat.Value = 'tif';
            app.OutputbinaryformatLabel = uilabel(app.GeneralTab);
            app.OutputbinaryformatLabel.Text = 'Output binary format';
            app.OutputFormat = uidropdown(app.GeneralTab);
            app.OutputFormat.Items = {'tif', 'bmp', 'png', 'jpg'};
            app.OutputFormat.ValueChangedFcn = createCallbackFcn(app, @OutputFormatList, true);
            app.OutputFormat.Value = 'tif';
            app.SaveCSV = uicheckbox(app.GeneralTab);
            app.SaveCSV.ValueChangedFcn = createCallbackFcn(app, @SaveCSVToggle, true);
            app.SaveCSV.Text = 'Statistics CSV';
            app.SaveCSV.Value = true;
            app.SaveBinary = uicheckbox(app.GeneralTab);
            app.SaveBinary.ValueChangedFcn = createCallbackFcn(app, @SaveBinaryToggle, true);
            app.SaveBinary.Text = 'Binary images';
            app.SaveBinary.Value = true;
            app.SaveComparison = uicheckbox(app.GeneralTab);
            app.SaveComparison.ValueChangedFcn = createCallbackFcn(app, @SaveComparisonToggle, true);
            app.SaveComparison.Text = 'Comparison images';
            app.ImageFormatLabel = uilabel(app.GeneralTab);
            app.ImageFormatLabel.Text = 'Image Format';
            app.ExportOptionsLabel = uilabel(app.GeneralTab);
            app.ExportOptionsLabel.Text = 'Export Options';
            app.ImageProcessingOptionsLabel = uilabel(app.GeneralTab);
            app.ImageProcessingOptionsLabel.Text = 'Image Processing Options';
            app.BinaryCSV = uicheckbox(app.GeneralTab);
            app.BinaryCSV.ValueChangedFcn = createCallbackFcn(app, @BinaryCSVToggle, true);
            app.BinaryCSV.Text = 'Binary CSV';
            app.InvertcolourCheckBox = uicheckbox(app.GeneralTab);
            app.InvertcolourCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertColourToggle, true);
            app.InvertcolourCheckBox.Text = 'Invert colour';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.CentreData = uicheckbox(app.AdvancedSettingsTab);
            app.CentreData.Text = 'Centre data';
            app.CentreData.Value = true;
            app.AnalyseAnnulus = uicheckbox(app.AdvancedSettingsTab);
            app.AnalyseAnnulus.Text = 'Analyse annulus';
            app.AnalyseAnnulus.Value = true;
            app.RadialbinsLabel = uilabel(app.AdvancedSettingsTab);
            app.RadialbinsLabel.Text = 'Radial bins';
            app.RadialBins = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.RadialBins.Limits = [1 Inf];
            app.RadialBins.Value = 200;
            app.AngularbinsLabel = uilabel(app.AdvancedSettingsTab);
            app.AngularbinsLabel.Text = 'Angular bins';
            app.AngularBins = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.AngularBins.Limits = [1 Inf];
            app.AngularBins.ValueChangedFcn = createCallbackFcn(app, @AngularBinsField, true);
            app.AngularBins.Value = 200;
            app.PairbinsLabel = uilabel(app.AdvancedSettingsTab);
            app.PairbinsLabel.Text = 'Pair bins';
            app.PairBins = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.PairBins.Limits = [1 Inf];
            app.PairBins.ValueChangedFcn = createCallbackFcn(app, @PairBinsField, true);
            app.PairBins.Value = 200;
            app.MaximumpairpixelsLabel = uilabel(app.AdvancedSettingsTab);
            app.MaximumpairpixelsLabel.Text = 'Maximum pair pixels';
            app.MaxCells = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.MaxCells.Limits = [1 Inf];
            app.MaxCells.ValueChangedFcn = createCallbackFcn(app, @MaxCellsField, true);
            app.MaxCells.Value = 1000;
            app.PairsamplesLabel = uilabel(app.AdvancedSettingsTab);
            app.PairsamplesLabel.Text = 'Pair samples';
            app.PairSamples = uieditfield(app.AdvancedSettingsTab, 'numeric');
            app.PairSamples.Limits = [1 Inf];
            app.PairSamples.ValueChangedFcn = createCallbackFcn(app, @PairSamplesToggle, true);
            app.PairSamples.Value = 1000;
            app.RadiusmethodLabel = uilabel(app.AdvancedSettingsTab);
            app.RadiusmethodLabel.Text = 'Radius method';
            app.RadiusMethod = uidropdown(app.AdvancedSettingsTab);
            app.RadiusMethod.Items = {'CSR', 'Punch'};
            app.RadiusMethod.Value = 'CSR';
            app.SaveIntensity = uicheckbox(app.AdvancedSettingsTab);
            app.SaveIntensity.ValueChangedFcn = createCallbackFcn(app, @SaveIntensityToggle, true);
            app.SaveIntensity.Text = 'Intensity plots';
            app.SaveError = uicheckbox(app.AdvancedSettingsTab);
            app.SaveError.ValueChangedFcn = createCallbackFcn(app, @SaveErrorToggle, true);
            app.SaveError.Text = 'Intensity with error';
            app.SaveMAT = uicheckbox(app.AdvancedSettingsTab);
            app.SaveMAT.ValueChangedFcn = createCallbackFcn(app, @SaveMATToggle, true);
            app.SaveMAT.Text = 'Statistics MAT file';
            app.StatisticsSettingsLabel = uilabel(app.AdvancedSettingsTab);
            app.StatisticsSettingsLabel.Text = 'Statistics Settings';
            app.AdditionalExportOptionsLabel = uilabel(app.AdvancedSettingsTab);
            app.AdditionalExportOptionsLabel.Text = 'Additional Export Options';
            app.Fourier = uicheckbox(app.AdvancedSettingsTab);
            app.Fourier.ValueChangedFcn = createCallbackFcn(app, @FourierToggle, true);
            app.Fourier.Text = 'Fourier coefficients';
            app.BinaryMATFile = uicheckbox(app.AdvancedSettingsTab);
            app.BinaryMATFile.ValueChangedFcn = createCallbackFcn(app, @BinaryMATToggle, true);
            app.BinaryMATFile.Text = 'Binary MAT file';
            app.RadiusmethodproportionSliderLabel = uilabel(app.AdvancedSettingsTab);
            app.RadiusmethodproportionSliderLabel.Text = 'Radius method proportion';
            app.RadiusMethodProportion = uislider(app.AdvancedSettingsTab);
            app.RadiusMethodProportion.Limits = [0 1];
            app.RadiusMethodProportion.ValueChangedFcn = createCallbackFcn(app, @RadiusProportionSlider, true);
            app.Wavenumbers = uicheckbox(app.AdvancedSettingsTab);
            app.Wavenumbers.ValueChangedFcn = createCallbackFcn(app, @WavenumberToggle, true);
            app.Wavenumbers.Text = 'Wave numbers';
            app.RadialMetric = uicheckbox(app.AdvancedSettingsTab);
            app.RadialMetric.ValueChangedFcn = createCallbackFcn(app, @RadialMetricToggle, true);
            app.RadialMetric.Text = 'Radial metric';
            app.AngularMetric = uicheckbox(app.AdvancedSettingsTab);
            app.AngularMetric.ValueChangedFcn = createCallbackFcn(app, @AngularMetricToggle, true);
            app.AngularMetric.Text = 'Angular metric';
            app.PairMetric = uicheckbox(app.AdvancedSettingsTab);
            app.PairMetric.ValueChangedFcn = createCallbackFcn(app, @PairMetricToggle, true);
            app.PairMetric.Text = 'Pair metric';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Idle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tammicol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end